classdef Series_Fourier_todas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes_Triangular               matlab.ui.control.UIAxes
        RepeticionesEditFieldLabel      matlab.ui.control.Label
        Repeticiones                    matlab.ui.control.EditField
        SeriesdefourierLabel            matlab.ui.control.Label
        CantidadPeriodosEditFieldLabel  matlab.ui.control.Label
        CantidadPeriodos                matlab.ui.control.EditField
        GenerarButton                   matlab.ui.control.Button
        UIAxes_Diente                   matlab.ui.control.UIAxes
        UIAxes_Sinusoidal               matlab.ui.control.UIAxes
        UIAxes_Cuadrada                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            %--------INPUT--------

amplitud=10;
f=10e3;
cant_periodos=str2double(app.CantidadPeriodos.Value);


repeticiones=str2double(app.Repeticiones.Value);
componente_frecuencia=30;


%--------PROCESS------

t=linspace(0,cant_periodos/f,500);


sinusoidal_0 = amplitud*sin(2*pi*f*t + 0);%---sen--

cuadrada = 0;

for n = 1.0:+2.0:repeticiones
    comp = (1/n)*sin(2*pi*n*f*t);
    if n == 1
    cuadrada = comp;
    else
     cuadrada   = cuadrada + comp;
    end

end

triangular = 0;
for n=1:2:(componente_frecuencia*2-1)

    triangular = triangular + (1/(n^2))*cos(2*pi*n*f*t);
end
triangular_ft = pi/2 - (4/pi)*triangular;


diente = 0;
for n=1:componente_frecuencia

    diente = diente + ((-1)^(n+1))*(1/n)*sin(2*pi*n*f*t);
end
diente_ft =((2*amplitud/pi))*diente;


%--------OUTPUT-------

plot(app.UIAxes_Sinusoidal,t,sinusoidal_0)
grid on

plot(app.UIAxes_Cuadrada,t,cuadrada)
grid on

plot(app.UIAxes_Triangular,t,triangular_ft)
grid on

plot(app.UIAxes_Diente,t,diente_ft)
grid on
close all


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_Triangular = uiaxes(app.UIFigure);
            title(app.UIAxes_Triangular, 'Triangular')
            xlabel(app.UIAxes_Triangular, 'T')
            ylabel(app.UIAxes_Triangular, 'V')
            app.RepeticionesEditFieldLabel = uilabel(app.UIFigure);
            app.RepeticionesEditFieldLabel.Text = 'Repeticiones';
            app.Repeticiones = uieditfield(app.UIFigure, 'text');
            app.SeriesdefourierLabel = uilabel(app.UIFigure);
            app.SeriesdefourierLabel.Text = 'Series de fourier';
            app.CantidadPeriodosEditFieldLabel = uilabel(app.UIFigure);
            app.CantidadPeriodosEditFieldLabel.Text = 'Cantidad Periodos';
            app.CantidadPeriodos = uieditfield(app.UIFigure, 'text');
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.UIAxes_Diente = uiaxes(app.UIFigure);
            title(app.UIAxes_Diente, 'Diente Sierra')
            xlabel(app.UIAxes_Diente, 'T')
            ylabel(app.UIAxes_Diente, 'V')
            app.UIAxes_Sinusoidal = uiaxes(app.UIFigure);
            title(app.UIAxes_Sinusoidal, 'Sinusoidal')
            xlabel(app.UIAxes_Sinusoidal, 'T')
            ylabel(app.UIAxes_Sinusoidal, 'V')
            app.UIAxes_Cuadrada = uiaxes(app.UIFigure);
            title(app.UIAxes_Cuadrada, 'Cuadrada')
            xlabel(app.UIAxes_Cuadrada, 'T')
            ylabel(app.UIAxes_Cuadrada, 'V')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Series_Fourier_todas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end