classdef Transformada_foruirer_graf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes_Tiempo               matlab.ui.control.UIAxes
        UIAxes_Frecuencia           matlab.ui.control.UIAxes
        TransformadadeFourierLabel  matlab.ui.control.Label
        Transformar                 matlab.ui.control.Button
        RepeticionesEditFieldLabel  matlab.ui.control.Label
        Repeticiones                matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Transformar
        function TransformarButtonPushed(app, event)


%------- INPUT -------
f=10e3;
cant_periodos=2;
resolucion=200;
rep=app.Repeticiones.Value;

%------- PROCESS------

t=linspace(0,cant_periodos/f,resolucion);

cuadrada = 0;

for n = 1.0:+2.0:rep
    comp = (1/n)*sin(2*pi*n*f*t);
    if n == 1
    cuadrada = comp;
    else
     cuadrada   = cuadrada + comp;
    end

end


ft_cuadrada = abs (fftshift(fft(cuadrada)));

%------- OUTPUT ------


plot(app.UIAxes_Tiempo,t,cuadrada)
grid on


plot(app.UIAxes_Frecuencia,ft_cuadrada)
grid on
close all


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_Tiempo = uiaxes(app.UIFigure);
            title(app.UIAxes_Tiempo, 'Funcion tiempo')
            xlabel(app.UIAxes_Tiempo, 'Tiempo')
            ylabel(app.UIAxes_Tiempo, '')
            app.UIAxes_Frecuencia = uiaxes(app.UIFigure);
            title(app.UIAxes_Frecuencia, 'Funcion frecuencia')
            xlabel(app.UIAxes_Frecuencia, 'Frecuencia')
            ylabel(app.UIAxes_Frecuencia, 'Y')
            app.TransformadadeFourierLabel = uilabel(app.UIFigure);
            app.TransformadadeFourierLabel.Text = 'Transformada de Fourier';
            app.Transformar = uibutton(app.UIFigure, 'push');
            app.Transformar.ButtonPushedFcn = createCallbackFcn(app, @TransformarButtonPushed, true);
            app.Transformar.Text = 'Transformar';
            app.RepeticionesEditFieldLabel = uilabel(app.UIFigure);
            app.RepeticionesEditFieldLabel.Text = 'Repeticiones';
            app.Repeticiones = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transformada_foruirer_graf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end